classdef HealthyPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HP                          matlab.ui.Figure
        HelpButton                  matlab.ui.control.Button
        UnitsDropDown               matlab.ui.control.DropDown
        UnitsDropDownLabel          matlab.ui.control.Label
        BMISourceLabel              matlab.ui.control.Label
        userAge                     matlab.ui.control.EditField
        AgeyearsEditFieldLabel_2    matlab.ui.control.Label
        userWeight                  matlab.ui.control.EditField
        WeightLabel                 matlab.ui.control.Label
        userHeight                  matlab.ui.control.EditField
        HeightcmLabel               matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        LabelTDEE                   matlab.ui.control.Label
        LabelBMR                    matlab.ui.control.Label
        LabelBMI                    matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        activityLevel               matlab.ui.control.DropDown
        ActivityLevelDropDownLabel  matlab.ui.control.Label
        userSex                     matlab.ui.control.DropDown
        SexDropDownLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function applyFontToAll(app, components, fontName) %function to apply font to all components
            for i = 1:length(components)
                comp = components(i);
                %If the component have a FontName property, set it
                if isprop(comp, 'FontName')
                    comp.FontName = fontName;
                end
                % If the component has children (like panels), go deeper
                if isprop(comp, 'Children')
                    applyFontToAll(app, comp.Children, fontName);
                end
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            %%This function allows components to operate from the start
            

        %Applying font
            desiredFont = 'BiauKai';
            
            %Get all commponents in the app
            components = findall(app.HP)

            %Recursively applying font to all components
            app.applyFontToAll(components, desiredFont)


        %Color Palette
            ebony = [0.26,0.35,0.24]
            pinkFont = [0.92,0.84,0.78]
            teaGreen = [202/255, 237/255, 184/255]
            chestnut = [0.67,0.31,0.20]
            caramel = [205/255, 155/255, 89/255]
            lemon = [237/255, 228/255, 144/255]

        %Color Changes
            app.UnitsDropDown.FontColor = chestnut
            app.UnitsDropDown.BackgroundColor = pinkFont
            app.UnitsDropDownLabel.FontColor = pinkFont

            app.userHeight.FontColor = chestnut
            app.userHeight.BackgroundColor = pinkFont

            app.userWeight.FontColor = chestnut
            app.userWeight.BackgroundColor = pinkFont

            app.userAge.FontColor = chestnut
            app.userAge.BackgroundColor = pinkFont

            app.userSex.FontColor = chestnut
            app.userSex.BackgroundColor = pinkFont
            
            app.activityLevel.FontColor = chestnut
            app.activityLevel.BackgroundColor = pinkFont

            app.HelpButton.BackgroundColor = chestnut

        %BMI Axes
            cla(app.UIAxes); % clears previous content

            xlim(app.UIAxes, [10 40]);
            ylim(app.UIAxes, [0 1]);

            BMIRange = [10, 18.5, 25, 30, 40];
            xticks(app.UIAxes, BMIRange);
            yticks(app.UIAxes, '');
            
            xlabel(app. UIAxes, 'BMI');
            ylabel(app.UIAxes, '');
            title(app.UIAxes, 'Your BMI Range', 'Color', pinkFont);
            app.UIAxes.XColor = pinkFont    %Change color for xlabel
            app.UIAxes.YColor = pinkFont
            
        %BMI Axes fills
            hold(app.UIAxes, 'on')

            x1 = [10 18.5 18.5 10];      % left, right, right, left
            y1 = [0 0 1 1];              % bottom, bottom, top, top
            fill(app.UIAxes, x1, y1, teaGreen);
            fill(app.UIAxes, [18.5 25 25 18.5], [0 0 1 1], lemon);
            fill(app.UIAxes, [25 30 30 25], [0 0 1 1], caramel);
            fill(app.UIAxes, [30 40 40 30], [0 0 1 1], chestnut);

            hold(app.UIAxes, 'off')
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Extract input first so the external function doesn't confuse 
            rawHeight = app.userHeight.Value;
            rawWeight = str2double(app.userWeight.Value);
            units = app.UnitsDropDown.Value;

            if  strcmp(units,'imperial (lb/ft)')
                %extract numbers from 5'2'' format
                feetHeight = str2double(extractBefore(rawHeight, "'"));
                inchesHeight = extractBetween(rawHeight, "'", '"'); %standard 5'2"
                if isempty(inchesHeight) %common mistake 5'2''
                inchesHeight = extractBetween(rawHeight, "'", "''"); 
                end
                
                inchesHeight = str2double(inchesHeight);
                %convert height to cm
                userHeight = 30.48*feetHeight + 2.54*inchesHeight;

                %convert weight to kg
                userWeight = rawWeight*0.4536;

            else %metric
                %convert from string to number
                userHeight = str2double(rawHeight);
                userWeight = rawWeight;
            end

            %Convert remaining variables
            sex = app.userSex.Value;
            age = str2double(app.userAge.Value);
            activityLevel = app.activityLevel.Value;
disp(userHeight)
disp(userWeight)
            %Calculating the values and printing
            [BMI, BMR, TDEE] = BMICalorieCalc(userHeight, userWeight, sex, ...
            age, activityLevel, units)

            if BMI < 18.5
                BMIText = 'Underweight'
            elseif BMI < 25
                BMIText = 'Healthy'
            elseif BMI < 30
                BMIText = 'Overweight'
            elseif BMI >= 30
                BMIText = 'Obesity'
            end

            app.LabelBMI.Text = sprintf('Your BMI is: %.1f (%s)', BMI, BMIText);
            app.LabelBMR.Text = sprintf('Your BMR is: %.1f kcal/day', BMR);
            app.LabelTDEE.Text = sprintf('Your TDEE is: %.1f kcal/day', TDEE);


            %%BMI Axes
            %{
            Calculate BMI Range for the height, etc.
            Fill in with colors and plot where it is. x = something for
            vertical line
            %}
            %Load BMI value from function BMICalorieCalc
     

            delete(findobj(app.UIAxes, 'Tag', 'UserBMI')); %Deletes the plotted line
            
            if ~isnan(BMI) %Only plots when BMI is not NaN
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [BMI, BMI], [0, 1], 'k--', 'LineWidth', 2, 'Tag', 'UserBMI');
            hold(app.UIAxes, 'off');
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.userHeight.Value = '';
            app.userWeight.Value = '';
            app.userSex.Value = 'male';
            app.userAge.Value = '';
            app.activityLevel.Value = 'Sedentary';

            app.LabelBMI.Text = sprintf('Your BMI is: ');
            app.LabelBMR.Text = sprintf('Your BMR is: ');
            app.LabelTDEE.Text = sprintf('Your TDEE is: ');

            delete(findobj(app.UIAxes, 'Tag', 'UserBMI')); %Deletes the plotted line
            
        end

        % Value changed function: UnitsDropDown
        function ValueChangedFcn(app, event)
            if event.PreviousValue == 0 & app.userHeight.Value ~= 0
                app.userHeight.Value = []; % Just a placeholder in case you want to trigger anything
            end            
        end

        % Button pushed function: HelpButton
        function activityExplanation(app, event)
            
            message = [
    "Select whichever activity level fits your daily activeness:" + newline + newline +...
    "Sedentary: little to no exercise" + newline + ...
    "Lightly active: light exercise/sports 1–3 days/week" + newline + ...
    "Moderately active: moderate exercise/sports 3–5 days/week" + newline + ...
    "Very active: hard exercise/sports 6–7 days a week" + newline + ...
    "Super active: very hard exercise & physical job or 2× training"
];
            boxtitle = ["Activity Level Explanation"];
            uialert(app.HP, message, boxtitle, 'Icon', 'info')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HP = uifigure('Visible', 'off');
            app.HP.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.HP);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'BMI')
            app.SexDropDownLabel = uilabel(app.HP);
            app.SexDropDownLabel.Tag = 'userSexLabel';
            app.SexDropDownLabel.Text = 'Sex';
            app.userSex = uidropdown(app.HP);
            app.userSex.Items = {'male', 'female'};
            app.userSex.Value = 'male';
            app.ActivityLevelDropDownLabel = uilabel(app.HP);
            app.ActivityLevelDropDownLabel.Tag = 'activityLevelLabel';
            app.ActivityLevelDropDownLabel.Text = 'Activity Level';
            app.activityLevel = uidropdown(app.HP);
            app.activityLevel.Items = {'Sedentary', 'Lightly active', 'Moderately active', 'Very active', 'Super active'};
            app.activityLevel.Value = 'Moderately active';
            app.CalculateButton = uibutton(app.HP, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LabelBMI = uilabel(app.HP);
            app.LabelBMI.Text = 'Your BMI is:';
            app.LabelBMR = uilabel(app.HP);
            app.LabelBMR.Text = 'Your BMR is:';
            app.LabelTDEE = uilabel(app.HP);
            app.LabelTDEE.Text = 'Your TDEE is:';
            app.ResetButton = uibutton(app.HP, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HeightcmLabel = uilabel(app.HP);
            app.HeightcmLabel.Tag = 'userHeightLabel';
            app.HeightcmLabel.Text = 'Height';
            app.userHeight = uieditfield(app.HP, 'text');
            app.WeightLabel = uilabel(app.HP);
            app.WeightLabel.Tag = 'userWeightLabel';
            app.WeightLabel.Text = 'Weight';
            app.userWeight = uieditfield(app.HP, 'text');
            app.AgeyearsEditFieldLabel_2 = uilabel(app.HP);
            app.AgeyearsEditFieldLabel_2.Tag = 'userAgeLabel';
            app.AgeyearsEditFieldLabel_2.Text = 'Age (years)';
            app.userAge = uieditfield(app.HP, 'text');
            app.BMISourceLabel = uilabel(app.HP);
            app.BMISourceLabel.Text = 'Source: NIH Calculate Your BMI - https://www.nhlbi.nih.gov/calculate-your-bmi';
            app.UnitsDropDownLabel = uilabel(app.HP);
            app.UnitsDropDownLabel.Tag = 'UnitsDropDownLabel';
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.HP);
            app.UnitsDropDown.Items = {'metric (kg/cm)', 'imperial (lb/ft)'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChangedFcn, true);
            app.UnitsDropDown.Value = 'imperial (lb/ft)';
            app.HelpButton = uibutton(app.HP, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @activityExplanation, true);
            app.HelpButton.Icon = fullfile(pathToMLAPP, 'QuestionMark.png');
            app.HelpButton.Text = '';
            % Show the figure after all components are created
            app.HP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HealthyPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HP)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HP)
        end
    end
end